classdef Air_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button                         matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        EditFrequencyEditFieldLabel    matlab.ui.control.Label
        EditFrequency                  matlab.ui.control.NumericEditField
        EditPhaseOffsetEditFieldLabel  matlab.ui.control.Label
        EditPhaseOffset                matlab.ui.control.NumericEditField
        EditSamplesPerFrameEditFieldLabel  matlab.ui.control.Label
        EditSamplesPerFrame            matlab.ui.control.NumericEditField
        EditAmplitudeEditFieldLabel    matlab.ui.control.Label
        EditAmplitude                  matlab.ui.control.NumericEditField
        Panel                          matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Pax 
    end
    
    methods (Access = public)
        
        function test(app)
           hMod = comm.OFDMModulator;
           hMod.FFTLength = 128;
           hMod.NumSymbols = 2;
           
           disp(hMod)
           
           showResourceMapping(hMod)
        end
        
        function test2(app)
            %sine = dsp.SineWave(amp,freq,phase,Name,Value)
            sine1 = dsp.SineWave();
            sine1.Amplitude = app.EditAmplitude.Value;
            sine1.Frequency = app.EditFrequency.Value;
            sine1.PhaseOffset = app.EditPhaseOffset.Value * pi/2;
            sine1.SamplesPerFrame = app.EditSamplesPerFrame.Value;
            y = sine1();
            
            plot(app.UIAxes , y );
            
        end
        
        function test3(app)

            hMod = comm.OFDMModulator;

            hMod.FFTLength = 128;
            hMod.NumSymbols = 2;

            hMod.PilotInputPort = true;

            hMod.PilotCarrierIndices = [12; 56; 89; 100];

            hMod.NumTransmitAntennas = 1 ;

        %    disp(hMod);
        %    showResourceMapping(hMod);

            gx = uiaxes("Parent", app.Panel);

        %     plot(app.Panel , gx);
        %    p = uipanel;
        %    p = uipanel(app.Panel);

        %    app.Panel(geoaxes);

        end
        
        function test4(app)
            
        %% Generate OFDM Waveform
        % OFDM configuration:
        ofdmMod = comm.OFDMModulator('FFTLength', 64, ...
        'NumGuardBandCarriers', [6;5], ...
        'InsertDCNull', false, ...
        'CyclicPrefixLength', [16], ...
        'Windowing', false, ...
        'NumSymbols', 100, ...
        'NumTransmitAntennas', 1, ...
        'PilotInputPort', false);
        
        M = 4; 	 % Modulation order
        % input bit source:
        in = randi([0 1], 10600, 1);
        
        dataInput = qammod(in, M, 'gray', 'InputType', 'bit', 'UnitAveragePower', true);
        ofdmInfo = info(ofdmMod);
        ofdmSize = ofdmInfo.DataInputSize;
        dataInput = reshape(dataInput, ofdmSize);
        
        % waveform generation:
        waveform = ofdmMod(dataInput);
        
        Fs = 6.4e+07; % sample rate of waveform
        
        %% Visualize OFDM Waveform
        % Spectrum Analyzer
        spectrum = dsp.SpectrumAnalyzer('SampleRate', Fs);
        spectrum(waveform);
        release(spectrum);
        
        % OFDM Subcarrier Mapping
        showResourceMapping(ofdmMod);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.test4()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Label = uilabel(app.UIFigure);
            app.EditFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.EditFrequencyEditFieldLabel.Text = 'Edit Frequency';
            app.EditFrequency = uieditfield(app.UIFigure, 'numeric');
            app.EditPhaseOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.EditPhaseOffsetEditFieldLabel.Text = 'Edit PhaseOffset';
            app.EditPhaseOffset = uieditfield(app.UIFigure, 'numeric');
            app.EditSamplesPerFrameEditFieldLabel = uilabel(app.UIFigure);
            app.EditSamplesPerFrameEditFieldLabel.Text = 'Edit SamplesPerFrame';
            app.EditSamplesPerFrame = uieditfield(app.UIFigure, 'numeric');
            app.EditAmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.EditAmplitudeEditFieldLabel.Text = 'Edit Amplitude';
            app.EditAmplitude = uieditfield(app.UIFigure, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Air_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end